classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_51                    matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        ShallownetworksTab              matlab.ui.container.Tab
        Button_2                        matlab.ui.control.Button
        NumberofhiddenneuronsEditField  matlab.ui.control.NumericEditField
        NumberofhiddenneuronsEditFieldLabel  matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        SoftMaxCheckBox                 matlab.ui.control.CheckBox
        ErrorWeitghsCheckBox            matlab.ui.control.CheckBox
        EncodingCheckBox                matlab.ui.control.CheckBox
        DatasetbalanceCheckBox          matlab.ui.control.CheckBox
        LearnfunctionDropDown           matlab.ui.control.DropDown
        LearnfunctionDropDownLabel      matlab.ui.control.Label
        ActivationfunctionDropDown_2    matlab.ui.control.DropDown
        ActivationfunctionDropDown_2Label  matlab.ui.control.Label
        EditField_49                    matlab.ui.control.EditField
        EditField_48                    matlab.ui.control.EditField
        ActivationfunctionDropDown      matlab.ui.control.DropDown
        ActivationfunctionDropDownLabel  matlab.ui.control.Label
        EditField_47                    matlab.ui.control.EditField
        SelectapatientDropDown          matlab.ui.control.DropDown
        SelectapatientDropDownLabel     matlab.ui.control.Label
        ShallowNetworksPanel            matlab.ui.container.Panel
        EditField_50                    matlab.ui.control.NumericEditField
        ArchitectureDropDown_3          matlab.ui.control.DropDown
        ArchitectureDropDown_3Label     matlab.ui.control.Label
        ThirdlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        ThirdlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        SecondlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        SecondlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        FirstlayeractivationfunctionDropDown  matlab.ui.control.DropDown
        FirstlayeractivationfunctionDropDownLabel  matlab.ui.control.Label
        NumberoflayersDropDown          matlab.ui.control.DropDown
        NumberoflayersDropDownLabel     matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DeepnetworksTab                 matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        HiddenunitsEditField            matlab.ui.control.NumericEditField
        HiddenunitsEditFieldLabel       matlab.ui.control.Label
        DeepNetworksPanel               matlab.ui.container.Panel
        ArchitectureDropDown_4          matlab.ui.control.DropDown
        ArchitectureDropDown_4Label     matlab.ui.control.Label
        CNNoptionsPanel                 matlab.ui.container.Panel
        LayersstrideEditField           matlab.ui.control.NumericEditField
        LayersstrideEditFieldLabel      matlab.ui.control.Label
        NumberoflayersEditField         matlab.ui.control.NumericEditField
        NumberoflayersEditFieldLabel    matlab.ui.control.Label
        NumberoffiltersEditField        matlab.ui.control.NumericEditField
        NumberoffiltersEditFieldLabel   matlab.ui.control.Label
        FiltersizeEditField             matlab.ui.control.NumericEditField
        FiltersizeEditFieldLabel        matlab.ui.control.Label
        PoolStrideEditField             matlab.ui.control.NumericEditField
        PoolStrideEditFieldLabel        matlab.ui.control.Label
        PoolsizeEditField               matlab.ui.control.NumericEditField
        PoolsizeEditFieldLabel          matlab.ui.control.Label
        PoolDropDown                    matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        EphocsEditField                 matlab.ui.control.NumericEditField
        EphocsEditFieldLabel            matlab.ui.control.Label
        EncodingCheckBox_2              matlab.ui.control.CheckBox
        DatasetbalanceCheckBox_2        matlab.ui.control.CheckBox
        SelectapatientDropDown_2        matlab.ui.control.DropDown
        SelectapatientDropDownLabel_2   matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        TestbestnetworksTab             matlab.ui.container.Tab
        SelectapatientDropDown_3        matlab.ui.control.DropDown
        SelectapatientDropDownLabel_3   matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        BestLSTMButton                  matlab.ui.control.Button
        BestCNNButton                   matlab.ui.control.Button
        BestrecurrentnetButton          matlab.ui.control.Button
        BestfeedforwardnetButton        matlab.ui.control.Button
        Image2_3                        matlab.ui.control.Image
        EditField_46                    matlab.ui.control.EditField
        SeizurebySeizurePanel           matlab.ui.container.Panel
        EditField_45                    matlab.ui.control.EditField
        EditField_44                    matlab.ui.control.EditField
        EditField_43                    matlab.ui.control.EditField
        EditField_42                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.EditField
        EditField_35                    matlab.ui.control.EditField
        PointbypointPanel               matlab.ui.container.Panel
        EditField_38                    matlab.ui.control.EditField
        EditField_37                    matlab.ui.control.EditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectapatientDropDown
        function SelectapatientDropDownValueChanged(app, event)
            value = app.SelectapatientDropDown.Value;
            if(value == "44202")
                app.EditField_20.Value=1;
            else
                app.EditField_20.Value=2;
            end
        end

        % Value changed function: ArchitectureDropDown_3
        function ArchitectureDropDownValueChanged(app, event)
            value = app.ArchitectureDropDown_3.Value;
            if(value=="feedforwardnet")
                 app.EditField_30.Value=1;
            else
                 app.EditField_30.Value=2;
            end
            app.ActivationfunctionDropDown.Enable="on";
            app.ActivationfunctionDropDown_2.Enable="on";
            app.LearnfunctionDropDown.Enable="on";
            app.NumberoflayersDropDown.Enable="on";
            app.FirstlayeractivationfunctionDropDown.Enable="on";
            app.SecondlayeractivationfunctionDropDown.Enable="off";
            app.ThirdlayeractivationfunctionDropDown.Enable="off";
            app.NumberoflayersDropDown.Value="1";
        end

        % Value changed function: NumberoflayersDropDown
        function NumberoflayersDropDownValueChanged(app, event)
            value = app.NumberoflayersDropDown.Value;
            if(value=="1")
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="off";
                app.ThirdlayeractivationfunctionDropDown.Enable="off";
            elseif(value=="2")
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="on";
                app.ThirdlayeractivationfunctionDropDown.Enable="off";
            else
                app.FirstlayeractivationfunctionDropDown.Enable="on";
                app.SecondlayeractivationfunctionDropDown.Enable="on";
                app.ThirdlayeractivationfunctionDropDown.Enable="on";
            end
        end

        % Value changed function: ActivationfunctionDropDown
        function ActivationfunctionDropDown_3ValueChanged(app, event)
            if(app.ActivationfunctionDropDown_3.Value=="trainlm")
                app.NumberofhiddenneuronsEditField.Limits=[1,20];
            end
            app.CheckBox.Value=1;
            app.CheckBox_2.Value=0;
            app.EditField_29.Value=1;
        end

        % Value changed function: ActivationfunctionDropDown_2
        function ActivationfunctionDropDown_4ValueChanged(app, event)
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=1;
            app.EditField_29.Value=1;
        end

        % Value changed function: LearnfunctionDropDown
        function LearnfunctionDropDown_2ValueChanged(app, event)
            app.CheckBox.Value=0;
            app.CheckBox_2.Value=1;
            app.EditField_29.Value=1;
        end

        % Value changed function: ArchitectureDropDown_4
        function ArchitectureDropDown_2ValueChanged(app, event)
            value = app.ArchitectureDropDown_4.Value;
            if(value=="CNN")
                 app.EditField_30.Value=3;
                 app.CNNoptionsPanel.Enable="on";
                 app.HiddenunitsEditField.Enable="off";
            else
                 app.EditField_30.Value=4;
                 app.CNNoptionsPanel.Enable="off";
                 app.HiddenunitsEditField.Enable="on";
            end
            app.ActivationfunctionDropDown.Enable="off";
            app.ActivationfunctionDropDown_2.Enable="off";
            app.LearnfunctionDropDown.Enable="off";
            app.NumberoflayersDropDown.Enable="off";
            app.FirstlayeractivationfunctionDropDown.Enable="off";
            app.SecondlayeractivationfunctionDropDown.Enable="off";
            app.ThirdlayeractivationfunctionDropDown.Enable="off";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if(floor(str2double(app.NumberoflayersDropDown.Value))<2)
                   actFun2="";
                   actFun3="";
            elseif(floor(str2double(app.NumberoflayersDropDown.Value))<3)
                    actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                    actFun3="";
            else
                   actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                   actFun3=app.ThirdlayeractivationfunctionDropDown.Value;
            end
            if(app.CheckBox.Value)
                trainFun=app.ActivationfunctionDropDown.Value;
                learnFun="";
            else
                trainFun=app.ActivationfunctionDropDown_2.Value;
                learnFun=app.LearnfunctionDropDown.Value;
            end
            if(app.EditField_30.Value==1 ||app.EditField_30.Value==2)
                [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=...
                shallowClassify(app.EditField_20.Value,app.DatasetbalanceCheckBox.Value, ...
                app.ErrorWeitghsCheckBox.Value, app.EncodingCheckBox.Value, app.EditField_30.Value, app.EditField_29.Value , ...
                trainFun, learnFun, floor(str2double(app.NumberoflayersDropDown.Value)),...
                app.NumberofhiddenneuronsEditField.Value, app.FirstlayeractivationfunctionDropDown.Value,...
                actFun2, actFun3, app.SoftMaxCheckBox.Value);
            elseif(app.EditField_30.Value==3)
                [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=cnnClassify(app.EditField_20.Value,...
                app.DatasetbalanceCheckBox_2.Value,...
                app.EncodingCheckBox_2.Value,...
                app.PoolDropDown.Value,...
                app.PoolsizeEditField.Value,...
                app.PoolStrideEditField.Value,...
                app.NumberoffiltersEditField.Value,...
                app.FiltersizeEditField.Value,...
                app.NumberoflayersEditField.Value,...
                app.LayersstrideEditField.Value,...
                app.EphocsEditField.Value);
            else
               [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=LSTM(app.EditField_20.Value,...
                app.DatasetbalanceCheckBox.Value,...
                app.ErrorWeitghsCheckBox.Value,...
                app.HiddenunitsEditField.Value, ...
                app.EphocsEditField.Value);
            end 
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            if(floor(str2double(app.NumberoflayersDropDown.Value))<2)
                   actFun2="";
                   actFun3="";
            elseif(floor(str2double(app.NumberoflayersDropDown.Value))<3)
                    actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                    actFun3="";
            else
                   actFun2=app.SecondlayeractivationfunctionDropDown.Value;
                   actFun3=app.ThirdlayeractivationfunctionDropDown.Value;
            end
            if(app.CheckBox.Value)
                trainFun=app.ActivationfunctionDropDown.Value;
                learnFun="";
            else
                trainFun=app.ActivationfunctionDropDown_2.Value;
                learnFun=app.LearnfunctionDropDown.Value;
            end
            if(app.EditField_30.Value==1 ||app.EditField_30.Value==2)
                [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=...
                shallowClassify(app.EditField_20.Value,app.DatasetbalanceCheckBox.Value, ...
                app.ErrorWeitghsCheckBox.Value, app.EncodingCheckBox.Value, app.EditField_30.Value, app.EditField_29.Value , ...
                trainFun, learnFun, floor(str2double(app.NumberoflayersDropDown.Value)),...
                app.NumberofhiddenneuronsEditField.Value, app.FirstlayeractivationfunctionDropDown.Value,...
                actFun2, actFun3, app.SoftMaxCheckBox.Value);
            elseif(app.EditField_30.Value==3)
                [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=cnnClassify(app.EditField_20.Value,...
                app.DatasetbalanceCheckBox_2.Value,...
                app.EncodingCheckBox_2.Value,...
                app.PoolDropDown.Value,...
                app.PoolsizeEditField.Value,...
                app.PoolStrideEditField.Value,...
                app.NumberoffiltersEditField.Value,...
                app.FiltersizeEditField.Value,...
                app.NumberoflayersEditField.Value,...
                app.LayersstrideEditField.Value,...
                app.EphocsEditField.Value);
            else
               [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=LSTM(app.EditField_20.Value,...
                app.DatasetbalanceCheckBox_2.Value,...
                app.EncodingCheckBox_2.Value,...
                app.HiddenunitsEditField.Value, ...
                app.EphocsEditField.Value);
            end 
        end

        % Value changed function: SelectapatientDropDown_2
        function SelectapatientDropDown_2ValueChanged(app, event)
            value = app.SelectapatientDropDown_2.Value;
            if(value == "44202")
                app.EditField_20.Value=1;
            else
                app.EditField_20.Value=2;
            end
        end

        % Button down function: DeepnetworksTab
        function DeepnetworksTabButtonDown(app, event)
            app.EditField_30.Value=3;
        end

        % Button down function: ShallownetworksTab
        function ShallownetworksTabButtonDown(app, event)
            app.EditField_30.Value=1;
        end

        % Button pushed function: BestfeedforwardnetButton
        function BestfeedforwardnetButtonPushed(app, event)
              [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=loadModel(app.EditField_51.Value,"44202_EW_FF_3L_10HN_trainlm_purelin_purelin_purelin_softmax.mat");
        end

        % Value changed function: SelectapatientDropDown_3
        function SelectapatientDropDown_3ValueChanged(app, event)
            value = app.SelectapatientDropDown_3.Value;
            if(value == "44202")
                app.EditField_51.Value=1;
            else
                app.EditField_51.Value=2;
            end
        end

        % Button pushed function: BestrecurrentnetButton
        function BestrecurrentnetButtonPushed(app, event)
                    [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=loadModel(app.EditField_51.Value,"44202_AUTO_BEW_DELAY_1L_10HN_trainlm_purelin_softmax.mat");
        end

        % Button pushed function: BestCNNButton
        function BestCNNButtonPushed(app, event)
            [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=cnnTest(app.EditField_51.Value,"CNN_44202_B1_PSize1_PStride1_NF20_FS10_NL2_LS1_E100.mat");
        end

        % Button pushed function: BestLSTMButton
        function BestLSTMButtonPushed(app, event)
            [app.EditField_32.Value, app.EditField_31.Value, app.EditField_33.Value, app.EditField_34.Value,... 
                app.EditField_40.Value, app.EditField_39.Value, app.EditField_41.Value ,app.EditField_42.Value]=LSTMtest(app.EditField_51.Value,"LSTM_44202_29F_30H_5Ep.mat");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField_20 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_20.Editable = 'off';
            app.EditField_20.Visible = 'off';
            app.EditField_20.Value = 1;
            app.EditField_29 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_29.Editable = 'off';
            app.EditField_29.Visible = 'off';
            app.EditField_30 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_30.Editable = 'off';
            app.EditField_30.Visible = 'off';
            app.EditField_30.Value = 1;
            app.PointbypointPanel = uipanel(app.UIFigure);
            app.PointbypointPanel.Title = 'Point-by-point';
            app.EditField_31 = uieditfield(app.PointbypointPanel, 'numeric');
            app.EditField_31.Editable = 'off';
            app.EditField_32 = uieditfield(app.PointbypointPanel, 'numeric');
            app.EditField_32.Editable = 'off';
            app.EditField_33 = uieditfield(app.PointbypointPanel, 'numeric');
            app.EditField_33.Editable = 'off';
            app.EditField_34 = uieditfield(app.PointbypointPanel, 'numeric');
            app.EditField_34.Editable = 'off';
            app.EditField_37 = uieditfield(app.PointbypointPanel, 'text');
            app.EditField_37.Editable = 'off';
            app.EditField_37.Value = 'Sensitivity';
            app.EditField_38 = uieditfield(app.PointbypointPanel, 'text');
            app.EditField_38.Editable = 'off';
            app.EditField_38.Value = 'Specificity';
            app.EditField_35 = uieditfield(app.UIFigure, 'text');
            app.EditField_35.Editable = 'off';
            app.EditField_35.Value = 'Prediction';
            app.EditField_36 = uieditfield(app.UIFigure, 'text');
            app.EditField_36.Editable = 'off';
            app.EditField_36.Value = 'Detection';
            app.SeizurebySeizurePanel = uipanel(app.UIFigure);
            app.SeizurebySeizurePanel.Title = 'Seizure-by-Seizure';
            app.EditField_39 = uieditfield(app.SeizurebySeizurePanel, 'numeric');
            app.EditField_39.Editable = 'off';
            app.EditField_40 = uieditfield(app.SeizurebySeizurePanel, 'numeric');
            app.EditField_40.Editable = 'off';
            app.EditField_41 = uieditfield(app.SeizurebySeizurePanel, 'numeric');
            app.EditField_41.Editable = 'off';
            app.EditField_42 = uieditfield(app.SeizurebySeizurePanel, 'numeric');
            app.EditField_42.Editable = 'off';
            app.EditField_43 = uieditfield(app.SeizurebySeizurePanel, 'text');
            app.EditField_43.Editable = 'off';
            app.EditField_43.Value = 'Sensitivity';
            app.EditField_44 = uieditfield(app.SeizurebySeizurePanel, 'text');
            app.EditField_44.Editable = 'off';
            app.EditField_44.Value = 'Specificity';
            app.EditField_45 = uieditfield(app.SeizurebySeizurePanel, 'text');
            app.EditField_45.Editable = 'off';
            app.EditField_45.Value = 'Prediction';
            app.EditField_46 = uieditfield(app.UIFigure, 'text');
            app.EditField_46.Editable = 'off';
            app.EditField_46.Value = 'Detection';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ShallownetworksTab = uitab(app.TabGroup);
            app.ShallownetworksTab.Title = 'Shallow networks';
            app.ShallownetworksTab.ButtonDownFcn = createCallbackFcn(app, @ShallownetworksTabButtonDown, true);
            app.Image2 = uiimage(app.ShallownetworksTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'a.jpg');
            app.ShallowNetworksPanel = uipanel(app.ShallownetworksTab);
            app.ShallowNetworksPanel.Title = 'Shallow Networks';
            app.NumberoflayersDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.NumberoflayersDropDownLabel.Text = 'Number of layers';
            app.NumberoflayersDropDown = uidropdown(app.ShallowNetworksPanel);
            app.NumberoflayersDropDown.Items = {'1', '2', '3'};
            app.NumberoflayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberoflayersDropDownValueChanged, true);
            app.NumberoflayersDropDown.Value = '1';
            app.FirstlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.FirstlayeractivationfunctionDropDownLabel.Text = 'First layer activation function';
            app.FirstlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.FirstlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.FirstlayeractivationfunctionDropDown.Value = 'purelin';
            app.SecondlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.SecondlayeractivationfunctionDropDownLabel.Enable = 'off';
            app.SecondlayeractivationfunctionDropDownLabel.Text = 'Second layer activation function';
            app.SecondlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.SecondlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.SecondlayeractivationfunctionDropDown.Enable = 'off';
            app.SecondlayeractivationfunctionDropDown.Value = 'purelin';
            app.ThirdlayeractivationfunctionDropDownLabel = uilabel(app.ShallowNetworksPanel);
            app.ThirdlayeractivationfunctionDropDownLabel.Enable = 'off';
            app.ThirdlayeractivationfunctionDropDownLabel.Text = 'Third layer activation function';
            app.ThirdlayeractivationfunctionDropDown = uidropdown(app.ShallowNetworksPanel);
            app.ThirdlayeractivationfunctionDropDown.Items = {'purelin', 'tansig', 'sigmoidal'};
            app.ThirdlayeractivationfunctionDropDown.Enable = 'off';
            app.ThirdlayeractivationfunctionDropDown.Value = 'purelin';
            app.ArchitectureDropDown_3Label = uilabel(app.ShallowNetworksPanel);
            app.ArchitectureDropDown_3Label.Text = 'Architecture';
            app.ArchitectureDropDown_3 = uidropdown(app.ShallowNetworksPanel);
            app.ArchitectureDropDown_3.Items = {'feedforwardnet', 'layrecnet'};
            app.ArchitectureDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ArchitectureDropDownValueChanged, true);
            app.ArchitectureDropDown_3.Value = 'feedforwardnet';
            app.EditField_50 = uieditfield(app.ShallowNetworksPanel, 'numeric');
            app.EditField_50.Editable = 'off';
            app.EditField_50.Visible = 'off';
            app.EditField_50.Value = 1;
            app.SelectapatientDropDownLabel = uilabel(app.ShallownetworksTab);
            app.SelectapatientDropDownLabel.Text = 'Select a patient';
            app.SelectapatientDropDown = uidropdown(app.ShallownetworksTab);
            app.SelectapatientDropDown.Items = {'44202', '63502'};
            app.SelectapatientDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectapatientDropDownValueChanged, true);
            app.SelectapatientDropDown.Value = '44202';
            app.EditField_47 = uieditfield(app.ShallownetworksTab, 'text');
            app.EditField_47.Value = 'Bacth training';
            app.ActivationfunctionDropDownLabel = uilabel(app.ShallownetworksTab);
            app.ActivationfunctionDropDownLabel.Text = 'Train function';
            app.ActivationfunctionDropDown = uidropdown(app.ShallownetworksTab);
            app.ActivationfunctionDropDown.Items = {'traingd', 'traingdm', 'trainlm'};
            app.ActivationfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionDropDown_3ValueChanged, true);
            app.ActivationfunctionDropDown.Value = 'traingd';
            app.EditField_48 = uieditfield(app.ShallownetworksTab, 'text');
            app.EditField_48.Value = 'Please select a training style: ';
            app.EditField_49 = uieditfield(app.ShallownetworksTab, 'text');
            app.EditField_49.Value = 'Incremental training';
            app.ActivationfunctionDropDown_2Label = uilabel(app.ShallownetworksTab);
            app.ActivationfunctionDropDown_2Label.Text = 'Train function';
            app.ActivationfunctionDropDown_2 = uidropdown(app.ShallownetworksTab);
            app.ActivationfunctionDropDown_2.Items = {'trainc', 'trainr'};
            app.ActivationfunctionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionDropDown_4ValueChanged, true);
            app.ActivationfunctionDropDown_2.Value = 'trainc';
            app.LearnfunctionDropDownLabel = uilabel(app.ShallownetworksTab);
            app.LearnfunctionDropDownLabel.Text = 'Learn function';
            app.LearnfunctionDropDown = uidropdown(app.ShallownetworksTab);
            app.LearnfunctionDropDown.Items = {'learngd', 'learngdm'};
            app.LearnfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @LearnfunctionDropDown_2ValueChanged, true);
            app.LearnfunctionDropDown.Value = 'learngd';
            app.DatasetbalanceCheckBox = uicheckbox(app.ShallownetworksTab);
            app.DatasetbalanceCheckBox.Text = 'Data set balance';
            app.EncodingCheckBox = uicheckbox(app.ShallownetworksTab);
            app.EncodingCheckBox.Text = 'Encoding';
            app.ErrorWeitghsCheckBox = uicheckbox(app.ShallownetworksTab);
            app.ErrorWeitghsCheckBox.Text = 'Error Weitghs';
            app.SoftMaxCheckBox = uicheckbox(app.ShallownetworksTab);
            app.SoftMaxCheckBox.Text = 'SoftMax';
            app.CheckBox = uicheckbox(app.ShallownetworksTab);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.ShallownetworksTab);
            app.CheckBox_2.Text = '';
            app.NumberofhiddenneuronsEditFieldLabel = uilabel(app.ShallownetworksTab);
            app.NumberofhiddenneuronsEditFieldLabel.Text = 'Number of hidden neurons';
            app.NumberofhiddenneuronsEditField = uieditfield(app.ShallownetworksTab, 'numeric');
            app.NumberofhiddenneuronsEditField.Limits = [1 50];
            app.NumberofhiddenneuronsEditField.Value = 25;
            app.Button_2 = uibutton(app.ShallownetworksTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images.png');
            app.Button_2.Text = '';
            app.DeepnetworksTab = uitab(app.TabGroup);
            app.DeepnetworksTab.Title = 'Deep networks';
            app.DeepnetworksTab.ButtonDownFcn = createCallbackFcn(app, @DeepnetworksTabButtonDown, true);
            app.Image2_2 = uiimage(app.DeepnetworksTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'a.jpg');
            app.SelectapatientDropDownLabel_2 = uilabel(app.DeepnetworksTab);
            app.SelectapatientDropDownLabel_2.Text = 'Select a patient';
            app.SelectapatientDropDown_2 = uidropdown(app.DeepnetworksTab);
            app.SelectapatientDropDown_2.Items = {'44202', '63502'};
            app.SelectapatientDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectapatientDropDown_2ValueChanged, true);
            app.SelectapatientDropDown_2.Value = '44202';
            app.DatasetbalanceCheckBox_2 = uicheckbox(app.DeepnetworksTab);
            app.DatasetbalanceCheckBox_2.Text = 'Data set balance';
            app.EncodingCheckBox_2 = uicheckbox(app.DeepnetworksTab);
            app.EncodingCheckBox_2.Text = 'Encoding';
            app.EphocsEditFieldLabel = uilabel(app.DeepnetworksTab);
            app.EphocsEditFieldLabel.Text = 'Ephocs';
            app.EphocsEditField = uieditfield(app.DeepnetworksTab, 'numeric');
            app.EphocsEditField.Limits = [1 100];
            app.EphocsEditField.Value = 1;
            app.CNNoptionsPanel = uipanel(app.DeepnetworksTab);
            app.CNNoptionsPanel.Title = 'CNN options:';
            app.Label = uilabel(app.CNNoptionsPanel);
            app.Label.Text = 'Pool:';
            app.PoolDropDown = uidropdown(app.CNNoptionsPanel);
            app.PoolDropDown.Items = {'max', 'average'};
            app.PoolDropDown.Value = 'max';
            app.PoolsizeEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.PoolsizeEditFieldLabel.Text = 'Pool size:';
            app.PoolsizeEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.PoolsizeEditField.Limits = [1 5];
            app.PoolsizeEditField.Value = 1;
            app.PoolStrideEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.PoolStrideEditFieldLabel.Text = 'Pool Stride:';
            app.PoolStrideEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.PoolStrideEditField.Limits = [1 10];
            app.PoolStrideEditField.Value = 1;
            app.FiltersizeEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.FiltersizeEditFieldLabel.Text = 'Filter size';
            app.FiltersizeEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.FiltersizeEditField.Limits = [1 50];
            app.FiltersizeEditField.Value = 1;
            app.NumberoffiltersEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.NumberoffiltersEditFieldLabel.Text = 'Number of filters';
            app.NumberoffiltersEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.NumberoffiltersEditField.Limits = [1 40];
            app.NumberoffiltersEditField.Value = 1;
            app.NumberoflayersEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.NumberoflayersEditFieldLabel.Text = 'Number of layers';
            app.NumberoflayersEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.NumberoflayersEditField.Limits = [1 5];
            app.NumberoflayersEditField.Value = 1;
            app.LayersstrideEditFieldLabel = uilabel(app.CNNoptionsPanel);
            app.LayersstrideEditFieldLabel.Text = 'Layers stride';
            app.LayersstrideEditField = uieditfield(app.CNNoptionsPanel, 'numeric');
            app.LayersstrideEditField.Limits = [1 5];
            app.LayersstrideEditField.Value = 1;
            app.DeepNetworksPanel = uipanel(app.DeepnetworksTab);
            app.DeepNetworksPanel.Title = 'Deep Networks';
            app.ArchitectureDropDown_4Label = uilabel(app.DeepNetworksPanel);
            app.ArchitectureDropDown_4Label.Text = 'Architecture';
            app.ArchitectureDropDown_4 = uidropdown(app.DeepNetworksPanel);
            app.ArchitectureDropDown_4.Items = {'CNN', 'LSTM'};
            app.ArchitectureDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ArchitectureDropDown_2ValueChanged, true);
            app.ArchitectureDropDown_4.Value = 'CNN';
            app.HiddenunitsEditFieldLabel = uilabel(app.DeepnetworksTab);
            app.HiddenunitsEditFieldLabel.Text = 'Hidden units';
            app.HiddenunitsEditField = uieditfield(app.DeepnetworksTab, 'numeric');
            app.HiddenunitsEditField.Limits = [1 100];
            app.HiddenunitsEditField.Enable = 'off';
            app.HiddenunitsEditField.Value = 1;
            app.Button = uibutton(app.DeepnetworksTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images.png');
            app.Button.Text = '';
            app.TestbestnetworksTab = uitab(app.TabGroup);
            app.TestbestnetworksTab.Title = 'Test best networks';
            app.Image2_3 = uiimage(app.TestbestnetworksTab);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'a.jpg');
            app.BestfeedforwardnetButton = uibutton(app.TestbestnetworksTab, 'push');
            app.BestfeedforwardnetButton.ButtonPushedFcn = createCallbackFcn(app, @BestfeedforwardnetButtonPushed, true);
            app.BestfeedforwardnetButton.Text = 'Best feedforwardnet';
            app.BestrecurrentnetButton = uibutton(app.TestbestnetworksTab, 'push');
            app.BestrecurrentnetButton.ButtonPushedFcn = createCallbackFcn(app, @BestrecurrentnetButtonPushed, true);
            app.BestrecurrentnetButton.Text = 'Best recurrent net';
            app.BestCNNButton = uibutton(app.TestbestnetworksTab, 'push');
            app.BestCNNButton.ButtonPushedFcn = createCallbackFcn(app, @BestCNNButtonPushed, true);
            app.BestCNNButton.Text = 'Best CNN';
            app.BestLSTMButton = uibutton(app.TestbestnetworksTab, 'push');
            app.BestLSTMButton.ButtonPushedFcn = createCallbackFcn(app, @BestLSTMButtonPushed, true);
            app.BestLSTMButton.Text = 'Best LSTM';
            app.Image4 = uiimage(app.TestbestnetworksTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'images.png');
            app.SelectapatientDropDownLabel_3 = uilabel(app.TestbestnetworksTab);
            app.SelectapatientDropDownLabel_3.Text = 'Select a patient';
            app.SelectapatientDropDown_3 = uidropdown(app.TestbestnetworksTab);
            app.SelectapatientDropDown_3.Items = {'44202', '63502'};
            app.SelectapatientDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SelectapatientDropDown_3ValueChanged, true);
            app.SelectapatientDropDown_3.Value = '44202';
            app.EditField_51 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_51.Editable = 'off';
            app.EditField_51.Visible = 'off';
            app.EditField_51.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end